classdef TableDataAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BloodPressureAnalyisisUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SystemTab                       matlab.ui.container.Tab
        pEditField                      matlab.ui.control.NumericEditField
        pEditFieldLabel                 matlab.ui.control.Label
        KalmanfilteringCheckBox         matlab.ui.control.CheckBox
        SimulateButton                  matlab.ui.control.Button
        UpdatereferenceButton           matlab.ui.control.Button
        toEditField                     matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        FromEditField                   matlab.ui.control.NumericEditField
        FromEditFieldLabel              matlab.ui.control.Label
        UpdatemembershipfuncttionsButton  matlab.ui.control.Button
        CostfunctiontypeListBox         matlab.ui.control.ListBox
        CostfunctiontypeListBoxLabel    matlab.ui.control.Label
        NumberofinputsEditField         matlab.ui.control.NumericEditField
        NumberofinputsEditFieldLabel    matlab.ui.control.Label
        NumberofstatesEditField         matlab.ui.control.NumericEditField
        NumberofstatesEditFieldLabel    matlab.ui.control.Label
        LoadworkspaceButton             matlab.ui.control.Button
        LoadworkspaceEditField          matlab.ui.control.EditField
        HorizonEditField                matlab.ui.control.NumericEditField
        HorizonEditFieldLabel           matlab.ui.control.Label
        DiscretizationtimeEditField     matlab.ui.control.NumericEditField
        DiscretizationtimeEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        NumberofIterationsEditField_2Label  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        systemfilenameEditField         matlab.ui.control.EditField
        systemfilenameEditFieldLabel    matlab.ui.control.Label
        SystemparametersLabel           matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MembershipfunctiosTab           matlab.ui.container.Tab
        MebershipPlot                   matlab.ui.control.UIAxes
        SimulationresultsTab            matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        StateTab                        matlab.ui.container.Tab
        Panel_state                     matlab.ui.container.Panel
        MembershipfunctionoferrorTab    matlab.ui.container.Tab
        Panel_membership_function_error  matlab.ui.container.Panel
    end

    
    properties (Access = private)
        model % Description
        trajectory % Description
        reference_subplots_axis={} % Description
        state_history=[] % Description
        modelDerievative
        modelObserverDerievative% Description
        kalmanState
        kalmanCovariance
    end

    methods (Access = private)
    
        function plot_reference(app)
            for i=app.reference_subplots_axis
                delete(i{1})
            end
            app.reference_subplots_axis={};
            x= linspace(0,app.DiscretizationtimeEditField.Value, app.NumberofIterationsEditField.Value);
            ref_full=app.trajectory;
            tableData = get(app.UITable, 'data');
            state_names=tableData(:,1);
            not_controlled_states = [tableData{:,3}];
            number_of_plots=length([state_names]);
            number_of_states=number_of_plots;
            for i=1:number_of_states
                if ~not_controlled_states(number_of_states+1-i)
                    ref_full(number_of_states-i+1,:)=[];
                    number_of_plots=number_of_plots-1;
                end
            end
            app.Panel_state.AutoResizeChildren ='off';
            for i=1:number_of_plots
                ax1 =subplot(number_of_plots,1,i,'Parent',app.Panel_state);
                plot(ax1, x,ref_full(i,1: app.NumberofIterationsEditField.Value))
                legend(ax1,'reference')
                ylabel(ax1,state_names{i})
                app.reference_subplots_axis{end+1}=ax1;
            end
            if number_of_plots>0
                xlabel(ax1,'Iteration')
            end
        end
        
        function simulate(app)
            fh = clf();
            ax = axes(fh,'Position',[.1 .4 .8 .05],'box','on','xtick',[],'ytick',[],...
                'color',[0.9375 0.9375 0.9375],'xlim',[0,1],'ylim',[0,1]); %gray94
            title(ax,'Progress')
            % Create empty patch that will be updated
            ph = patch(ax,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            % Create the percent-complete text that will be updated
            th = text(ax,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right'); 
            NUMBER_OF_ITERATIONS=app.NumberofIterationsEditField.Value;
            NUMBER_OF_INPUTS=app.NumberofinputsEditField.Value;
            input=[0,1;0,0];
            [~,state,not_controlled_states,fuzzy_membership_type,fuzzy_parameter,fuzzy_weights,measurment_error,system_errror,weights_types]=getTableData(app);
            app.kalmanState=state;
            noised_state=state;
            app.kalmanCovariance=diag(ones([1,length(state)])*100000);
            system_errror=system_errror*app.DiscretizationtimeEditField.Value;
            app.state_history=zeros(NUMBER_OF_ITERATIONS,app.NumberofstatesEditField.Value);
            assignin('base','discretization_time',app.DiscretizationtimeEditField.Value)
            for i=1:app.NumberofIterationsEditField.Value
                assignin('base','state',state)
                horizon=min(app.HorizonEditField.Value,NUMBER_OF_ITERATIONS-i+1);
                ref=app.trajectory(:,i:(i+horizon));
                
                
                val=fuzzy_MPC(app.DiscretizationtimeEditField.Value,app.model,NUMBER_OF_INPUTS,...
                    horizon,transpose(ref),noised_state,app.CostfunctiontypeListBox.Value,fuzzy_membership_type,fuzzy_parameter,...
                    fuzzy_weights,not_controlled_states,weights_types,app.pEditField.Value,[],[],[],[],-ones(NUMBER_OF_INPUTS*horizon,1),ones(NUMBER_OF_INPUTS*horizon,1));
                
                input(:,2:(NUMBER_OF_INPUTS+1))=[val';val'];
                assignin('base','input',input)
                output=sim(app.systemfilenameEditField.Value);
                state=output.simout.Data(end,:);
                
                state=addNoise(app,state,system_errror);
                noised_state = addNoise(app,state,measurment_error);
                if app.KalmanfilteringCheckBox.Value
                    noised_state=kalman_filtering(app,val,noised_state,app.DiscretizationtimeEditField.Value,system_errror,measurment_error);
                end
                
                app.state_history(i,:)=state; 
                if mod(i,5)==4
                    ph.XData = [0 i/app.NumberofIterationsEditField.Value i/app.NumberofIterationsEditField.Value 0]; 
                    th.String = sprintf('%.0f%%',round(i/app.NumberofIterationsEditField.Value*100)); 
                    drawnow %update graphics
                end
            end
            %draw_state_wtih_ref(app.state_history,transpose(app.trajectory),not_controlled_states,state_names,fuzzy_membership_type,fuzzy_parameter,app.CostfunctiontypeListBox.Value)
            app.plot_simulation()

        end

        function plot_simulation(app)            
            [state_names,~,not_controlled_states,fuzzy_membership_type,fuzzy_parameter,fuzzy_weights,~,~,types_of_fuzzy_weights]=getTableData(app);
            for i=app.reference_subplots_axis
                delete(i{1})
            end
            app.reference_subplots_axis={};
            x= linspace(0,app.DiscretizationtimeEditField.Value*app.NumberofIterationsEditField.Value, app.NumberofIterationsEditField.Value);
            ref_full=app.trajectory;
            number_of_plots=length([state_names]);
            state=app.state_history;
            for i=wrev(sort(not_controlled_states))
                state(:,i)=[];
                ref_full(i,:)=[];
                state_names(i,:)=[];
                number_of_plots=number_of_plots-1;
            end
            size_states=size(state);
            app.Panel_state.AutoResizeChildren ='off';
            for i=1:number_of_plots
                ax1 =subplot(number_of_plots,1,i,'Parent',app.Panel_state);
                plot(ax1, x,ref_full(i,1: app.NumberofIterationsEditField.Value))
                hold(ax1, 'on')
                plot(ax1, x,state(1: app.NumberofIterationsEditField.Value,i))
                legend(ax1,'reference','state')
                ylabel(ax1,state_names{i})
                app.reference_subplots_axis{end+1}=ax1;
            end
            if number_of_plots>0
                xlabel(ax1,'time')
            end
            app.Panel_membership_function_error.AutoResizeChildren ='off';
            membership=zeros(size_states);
            for i=1:size_states(2)
                errors = state(:,i)-ref_full(i,1:size_states(1))';
                for j=1:size_states(1)
                    if strcmpi(fuzzy_membership_type{i},'tringular')
                        membership(j,i) = fuzzy_tringular([-fuzzy_parameter(i),0,fuzzy_parameter(i)],errors(j));
                    elseif strcmpi(fuzzy_membership_type{i},'gauss')
                        membership(j,i) = fuzzy_gauss(fuzzy_parameter(i),errors(j));
                    end
                end
                if strcmpi(types_of_fuzzy_weights{i},'power')
                    membership(:,i)=membership(:,i).^fuzzy_weights(i);
                elseif strcmpi(types_of_fuzzy_weights{i},'barrier')
                    membership(:,i)=membership(:,i)*(1-fuzzy_weights(i))+fuzzy_weights(i);
                end
            end
            ax =subplot(2,1,1,'Parent',app.Panel_membership_function_error);
            plot(ax,x,membership(1: app.NumberofIterationsEditField.Value,:))
            legend(ax,state_names)
            
            ylabel('membership')
            if size_states(2)>1
                if strcmpi(app.CostfunctiontypeListBox.Value,'min')
                    membership=min(transpose(membership));
                elseif strcmpi(app.CostfunctiontypeListBox.Value,'prod')
                    membership=prod(membership,2);
                elseif strcmpi(app.CostfunctiontypeListBox.Value,'sum')
                    membership=sum(transpose(membership));
                elseif strcmpi(app.CostfunctiontypeListBox.Value,'Yager')
                    membership=(1-membership).^app.pEditField.Value;
                    membership=sum(membership').^(1/app.pEditField.Value);
                    membership=1-min(1,membership);

                end
                ax =subplot(2,1,2,'Parent',app.Panel_membership_function_error);
                plot(ax,x,membership(1: app.NumberofIterationsEditField.Value))
                ylabel('membership')
                legend(ax,'final membership')
            end

            xlabel('time')


        end

        function [state_names,state,not_controlled_states,fuzzy_membership_type,fuzzy_parameter,fuzzy_weights,measurment_error,system_error,fuzzy_weighting_type]=getTableData(app)
            tableData = get(app.UITable, 'data');
            state_names=tableData(:,1);
            state=[tableData{:,2}];
            helper=[tableData{:,3}];
            not_controlled_states=[];
            for i=1:length(helper)
                if ~helper(i)
                    not_controlled_states(end+1)=i;
                end
            end
            fuzzy_membership_type=tableData(:,4);
            fuzzy_parameter=[tableData{:,5}];
            fuzzy_weights=[tableData{:,6}];
            measurment_error=[tableData{:,7}];
            system_error=[tableData{:,8}];
            fuzzy_weighting_type=tableData(:,9);
        end


        function systemState = addNoise(app,systemState,system_error)
            for i=1:length(systemState)
                if system_error(i)>0
                    systemState(i)=normrnd(systemState(i),system_error(i));
                end
            end
        end
        
        function results = kalman_filtering(app,input,noised_state,time_step,R,Q)
            state_prediction= app.model(input,app.kalmanState,time_step);
            F = app.modelDerievative(state_prediction,time_step);
            kalmanCovariancePrediction=F*app.kalmanCovariance*F'+R; 
            H=app.modelObserverDerievative(state_prediction);
            error=noised_state'-H*state_prediction';
            S=H*kalmanCovariancePrediction*H'+Q;
            K=(kalmanCovariancePrediction*H'*S)^(-1);
            app.kalmanState=state_prediction+(K*error)';
            app.kalmanCovariance=(eye(length(state_prediction))-K*H)*kalmanCovariancePrediction;
            results=app.kalmanState;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Button pushed function: LoadworkspaceButton
        function LoadworkspaceButtonPushed(app, event)
            load(app.LoadworkspaceEditField.Value);
            try
                app.NumberofIterationsEditField.Value=NUMBER_OF_ITERATIONS;
            catch
            end
            try
                app.DiscretizationtimeEditField.Value=discretization_time;
            catch
            end
            try
                app.HorizonEditField.Value=horizon;
            catch
            end
            app.model = @(input,state,timeStep) system_model(input,state,timeStep);
            app.modelDerievative = @(state,timeStep) system_model_derievative(state,timeStep);
            app.modelObserverDerievative = @(state) system_observer_model_derievative(state);
            app.trajectory=ref_full;
            app.systemfilenameEditField.Value=system_simulink_name;
            app.NumberofstatesEditField.Value=length([state_names]);
            app.NumberofinputsEditField.Value=NUMBER_OF_INPUTS;
            app.UITable.ColumnFormat = {'char', 'bank', 'logical',{'tringular', 'gauss'},'bank','bank','bank','bank',{'power','barrier'}};
            weight_type=cell(size(state'));
            weight_type(:)={'power'};
            app.UITable.Data=[state_names', num2cell(state'), num2cell(ismember(1:length(state),not_controlled_states)'),...
fuzzy_membership_type',num2cell(fuzzy_parameter'),num2cell(fuzzy_weights'),num2cell(ones([length(state),1])*0.01),num2cell(ones([length(state),1])*0.01),weight_type];
            plot_reference(app);


        end

        % Button pushed function: UpdatemembershipfuncttionsButton
        function UpdatemembershipfuncttionsButtonPushed(app, event)
           cla(app.MebershipPlot);
           input = linspace(app.FromEditField.Value,app.toEditField.Value,1000);
           membership=zeros(1000, app.NumberofstatesEditField.Value);
           tableData = get(app.UITable, 'data');
           val=[tableData{:,3}];
           names=cell(sum(val),1);
           k=1;
           for i=1:app.NumberofstatesEditField.Value
                if tableData{i,3}==0
                    continue
                end
                names{k}=tableData{i,1};
                k=k+1;
                if strcmpi(tableData{i,4},'tringular')
                    for j=1:1000
                        membership(j,i) = fuzzy_tringular([-tableData{i,5},0,tableData{i,5}],input(j));
                    end
                elseif strcmpi( tableData{i,4},'gauss')
                        membership(:,i) = fuzzy_gauss(tableData{i,5},input);
                end
                if strcmpi(tableData{i,9},'power')
                    membership(:,i)=membership(:,i).^tableData{i,6};
                elseif strcmpi(tableData{i,9},'barrier')
                    membership(:,i)=membership(:,i)*(1-tableData{i,6})+tableData{i,6};
                end
                
           end
           plot(app.MebershipPlot,input,membership)
           legend(app.MebershipPlot,names)
           ylabel(app.MebershipPlot,'Membership')

        end

        % Button pushed function: UpdatereferenceButton
        function UpdatereferenceButtonPushed(app, event)
            plot_reference(app)
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            simulate(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BloodPressureAnalyisisUIFigure = uifigure('Visible', 'off');
            app.BloodPressureAnalyisisUIFigure.Name = 'Blood Pressure Analyisis';
            app.TabGroup = uitabgroup(app.BloodPressureAnalyisisUIFigure);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.Label = uilabel(app.SystemTab);
            app.Label.Text = '';
            app.SystemparametersLabel = uilabel(app.SystemTab);
            app.SystemparametersLabel.Text = 'System parameters';
            app.systemfilenameEditFieldLabel = uilabel(app.SystemTab);
            app.systemfilenameEditFieldLabel.Text = 'system file name';
            app.systemfilenameEditField = uieditfield(app.SystemTab, 'text');
            app.systemfilenameEditField.Editable = 'off';
            app.UITable = uitable(app.SystemTab);
            app.UITable.ColumnName = {'State names'; 'Initial condtions'; 'Tracked'; 'Membershi functions type'; 'Parameter'; 'Weight'; 'Measurment error'; 'System error'; 'type of wieghts'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.NumberofIterationsEditField_2Label = uilabel(app.SystemTab);
            app.NumberofIterationsEditField_2Label.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.SystemTab, 'numeric');
            app.NumberofIterationsEditField.Value = 50;
            app.DiscretizationtimeEditFieldLabel = uilabel(app.SystemTab);
            app.DiscretizationtimeEditFieldLabel.Text = 'Discretization time';
            app.DiscretizationtimeEditField = uieditfield(app.SystemTab, 'numeric');
            app.DiscretizationtimeEditField.Value = 0.1;
            app.HorizonEditFieldLabel = uilabel(app.SystemTab);
            app.HorizonEditFieldLabel.Text = 'Horizon';
            app.HorizonEditField = uieditfield(app.SystemTab, 'numeric');
            app.HorizonEditField.Value = 10;
            app.LoadworkspaceEditField = uieditfield(app.SystemTab, 'text');
            app.LoadworkspaceEditField.Value = 'model1D.mat';
            app.LoadworkspaceButton = uibutton(app.SystemTab, 'push');
            app.LoadworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadworkspaceButtonPushed, true);
            app.LoadworkspaceButton.Text = 'Load workspace';
            app.NumberofstatesEditFieldLabel = uilabel(app.SystemTab);
            app.NumberofstatesEditFieldLabel.Text = 'Number of states';
            app.NumberofstatesEditField = uieditfield(app.SystemTab, 'numeric');
            app.NumberofstatesEditField.Editable = 'off';
            app.NumberofinputsEditFieldLabel = uilabel(app.SystemTab);
            app.NumberofinputsEditFieldLabel.Text = 'Number of inputs';
            app.NumberofinputsEditField = uieditfield(app.SystemTab, 'numeric');
            app.NumberofinputsEditField.Editable = 'off';
            app.CostfunctiontypeListBoxLabel = uilabel(app.SystemTab);
            app.CostfunctiontypeListBoxLabel.Text = 'Cost function type';
            app.CostfunctiontypeListBox = uilistbox(app.SystemTab);
            app.CostfunctiontypeListBox.Items = {'min', 'sum', 'prod,', 'Yager'};
            app.CostfunctiontypeListBox.Value = 'min';
            app.UpdatemembershipfuncttionsButton = uibutton(app.SystemTab, 'push');
            app.UpdatemembershipfuncttionsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatemembershipfuncttionsButtonPushed, true);
            app.UpdatemembershipfuncttionsButton.Text = 'Update membership functtions';
            app.FromEditFieldLabel = uilabel(app.SystemTab);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.SystemTab, 'numeric');
            app.FromEditField.Value = -5;
            app.toEditFieldLabel = uilabel(app.SystemTab);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.SystemTab, 'numeric');
            app.toEditField.Value = 5;
            app.UpdatereferenceButton = uibutton(app.SystemTab, 'push');
            app.UpdatereferenceButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatereferenceButtonPushed, true);
            app.UpdatereferenceButton.Text = 'Update reference';
            app.SimulateButton = uibutton(app.SystemTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.KalmanfilteringCheckBox = uicheckbox(app.SystemTab);
            app.KalmanfilteringCheckBox.Text = 'Kalman filtering';
            app.pEditFieldLabel = uilabel(app.SystemTab);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.SystemTab, 'numeric');
            app.pEditField.Value = 1;
            app.MembershipfunctiosTab = uitab(app.TabGroup);
            app.MembershipfunctiosTab.Title = 'Membership functios';
            app.MebershipPlot = uiaxes(app.MembershipfunctiosTab);
            title(app.MebershipPlot, 'Title')
            xlabel(app.MebershipPlot, 'X')
            ylabel(app.MebershipPlot, 'Y')
            zlabel(app.MebershipPlot, 'Z')
            app.SimulationresultsTab = uitab(app.TabGroup);
            app.SimulationresultsTab.Title = 'Simulation results';
            app.TabGroup2 = uitabgroup(app.SimulationresultsTab);
            app.StateTab = uitab(app.TabGroup2);
            app.StateTab.Title = 'State';
            app.Panel_state = uipanel(app.StateTab);
            app.Panel_state.Title = 'Panel';
            app.MembershipfunctionoferrorTab = uitab(app.TabGroup2);
            app.MembershipfunctionoferrorTab.Title = 'Membership function of error';
            app.Panel_membership_function_error = uipanel(app.MembershipfunctionoferrorTab);
            app.Panel_membership_function_error.Title = 'Panel_membership_function_error';
            % Show the figure after all components are created
            app.BloodPressureAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TableDataAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BloodPressureAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BloodPressureAnalyisisUIFigure)
        end
    end
end